classdef rba_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RBAVisualizerUIFigure           matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        AlgorithmtouseDropDownLabel     matlab.ui.control.Label
        AlgorithmtouseDropDown          matlab.ui.control.DropDown
        StepButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        StartanimationButton            matlab.ui.control.Button
        AnimationstepdelaysecEditFieldLabel  matlab.ui.control.Label
        AnimationstepdelaysecEditField  matlab.ui.control.NumericEditField
        PauseanimationButton            matlab.ui.control.StateButton
    end

    properties (Access = private)
        alg
        data
        vis_delay
    end    
    methods (Access = private)
        
        function [] = init_plot(app)
            app.UIAxes.BackgroundColor = [1 1 1];
            scatter3(app.UIAxes, app.data(:, 1), app.data(:, 2), app.data(:, 3), 30, categorical(app.data(:, 4)), 'filled');
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
        end
    end    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.alg = "RELIEF";
            addpath('./RELIEF');
            app.data = load('rba_test_data2.m');
            app.init_plot()
            addprop(app.ResetButton, 'Flag');
            app.ResetButton.Flag = 0;
            app.vis_delay = app.AnimationstepdelaysecEditField.Value;
            create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, 0.0);
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            if app.ResetButton.Flag
                app.ResetButton.Flag = 0;
                create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, 0.0);
            else
                uiresume(app.RBAVisualizerUIFigure);
            end
        end

        % Value changed function: AlgorithmtouseDropDown
        function AlgorithmtouseDropDownValueChanged(app, event)
            app.alg = app.AlgorithmtouseDropDown.Value;
            switch(app.alg)
                case "RELIEF"
                    app.ResetButton.Flag = 0; pause(0.2);
                    cla(app.UIAxes);
                    addpath('./RELIEF');
                    app.init_plot()
                    create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, 0.0);
                case "RELIEFF"
                    app.ResetButton.Flag = 0; pause(0.2);
                    cla(app.UIAxes);
                    addpath('./RELIEFF');
                    app.init_plot()
                    create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, 0.0);
                case "Iterative RELIEF"
                    app.ResetButton.Flag = 0; pause(0.2);
                    cla(app.UIAxes);
                    addpath('./ITERATIVE-RELIEF');
                    app.init_plot()
                    create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, 0.0);
                case "I-RELIEF"
                    app.ResetButton.Flag = 0; pause(0.2);
                    cla(app.UIAxes);
                    addpath('./I-RELIEF');
                    app.init_plot()
                        create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, 0.0);
            end
        end

        % Button pushed function: StartanimationButton
        function StartanimationButtonPushed(app, event)
            app.ResetButton.Flag = 0; pause(0.2);
            cla(app.UIAxes);
            switch(app.alg)
                case "RELIEF"
                    addpath('./RELIEF');
                    app.init_plot();
                    app.ResetButton.Flag = 0;
                    create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, app.vis_delay);
                case "RELIEFF"
                    addpath('./RELIEFF');
                    app.init_plot();
                    app.ResetButton.Flag = 0;
                    create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, app.vis_delay);
                case "Iterative RELIEF"
                    addpath('./ITERATIVE-RELIEFF');
                    app.init_plot();
                    app.ResetButton.Flag = 0;
                    create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, app.vis_delay);
                case "I-RELIEF"
                    addpath('./I-RELIEFF');
                    app.init_plot();
                    app.ResetButton.Flag = 0;
                    create_animation(app.UIAxes, app.RBAVisualizerUIFigure, app.PauseanimationButton, app.ResetButton, app.vis_delay);
            end
        end

        % Value changed function: AnimationstepdelaysecEditField
        function AnimationstepdelaysecEditFieldValueChanged(app, event)
            app.vis_delay = app.AnimationstepdelaysecEditField.Value;
        end

        % Value changed function: PauseanimationButton
        function PauseanimationButtonValueChanged(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ResetButton.Flag = 1;
            cla(app.UIAxes);
            app.init_plot();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RBAVisualizerUIFigure = uifigure;
            app.RBAVisualizerUIFigure.Name = 'RBA Visualizer';
            app.UIAxes = uiaxes(app.RBAVisualizerUIFigure);
            title(app.UIAxes, 'Algorithm Visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AlgorithmtouseDropDownLabel = uilabel(app.RBAVisualizerUIFigure);
            app.AlgorithmtouseDropDownLabel.Text = 'Algorithm to use';
            app.AlgorithmtouseDropDown = uidropdown(app.RBAVisualizerUIFigure);
            app.AlgorithmtouseDropDown.Items = {'RELIEF', 'RELIEFF', 'Iterative RELIEF', 'I-RELIEF'};
            app.AlgorithmtouseDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmtouseDropDownValueChanged, true);
            app.AlgorithmtouseDropDown.Value = 'RELIEF';
            app.StepButton = uibutton(app.RBAVisualizerUIFigure, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.ResetButton = uibutton(app.RBAVisualizerUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartanimationButton = uibutton(app.RBAVisualizerUIFigure, 'push');
            app.StartanimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartanimationButtonPushed, true);
            app.StartanimationButton.Text = 'Start animation';
            app.AnimationstepdelaysecEditFieldLabel = uilabel(app.RBAVisualizerUIFigure);
            app.AnimationstepdelaysecEditFieldLabel.Text = 'Animation step delay (sec)';
            app.AnimationstepdelaysecEditField = uieditfield(app.RBAVisualizerUIFigure, 'numeric');
            app.AnimationstepdelaysecEditField.Limits = [0.01 Inf];
            app.AnimationstepdelaysecEditField.ValueChangedFcn = createCallbackFcn(app, @AnimationstepdelaysecEditFieldValueChanged, true);
            app.AnimationstepdelaysecEditField.Value = 0.5;
            app.PauseanimationButton = uibutton(app.RBAVisualizerUIFigure, 'state');
            app.PauseanimationButton.ValueChangedFcn = createCallbackFcn(app, @PauseanimationButtonValueChanged, true);
            app.PauseanimationButton.Text = 'Pause animation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rba_visualizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RBAVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RBAVisualizerUIFigure)
        end
    end
end