classdef itree_vis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ItreeVisualizerUIFigure      matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        VisualizeButton              matlab.ui.control.Button
        TreeheightlimitSpinnerLabel  matlab.ui.control.Label
        TreeheightlimitSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        examples
        target
        level_lim
        itree
        planes
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./utils');
            ld_examples = load('examples.mat'); app.examples = ld_examples.examples;
            ld_target = load('target.mat'); app.target = ld_target.target;
            app.itree = get_random_itree(app.examples);
            app.level_lim = 0;
            scatter3(app.UIAxes, app.examples(:, 1), app.examples(:, 2), app.examples(:, 3), 30, categorical(app.target'), 'filled');
            xlabel(app.UIAxes, 'a'); ylabel(app.UIAxes, 'b'); zlabel(app.UIAxes, 'c');
            hold(app.UIAxes, 'on' );
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            app.level_lim = app.EditField.Value;
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            cla(app.UIAxes); hold(app.UIAxes, 'on' );
            scatter3(app.UIAxes, app.examples(:, 1), app.examples(:, 2), app.examples(:, 3), 30, categorical(app.target'), 'filled');
            linear_partitioning_visualization(app.itree, [0, 1; 0, 1; 0, 1], app.level_lim, app.UIAxes);
        end

        % Value changed function: TreeheightlimitSpinner
        function TreeheightlimitSpinnerValueChanged(app, event)
            app.level_lim = app.TreeheightlimitSpinner.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ItreeVisualizerUIFigure = uifigure;
            app.ItreeVisualizerUIFigure.Name = 'I-tree Visualizer';
            app.UIAxes = uiaxes(app.ItreeVisualizerUIFigure);
            title(app.UIAxes, 'Space Partitioning Visualization (I-tree)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VisualizeButton = uibutton(app.ItreeVisualizerUIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.TreeheightlimitSpinnerLabel = uilabel(app.ItreeVisualizerUIFigure);
            app.TreeheightlimitSpinnerLabel.Text = 'Tree height limit';
            app.TreeheightlimitSpinner = uispinner(app.ItreeVisualizerUIFigure);
            app.TreeheightlimitSpinner.Limits = [0 Inf];
            app.TreeheightlimitSpinner.ValueChangedFcn = createCallbackFcn(app, @TreeheightlimitSpinnerValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = itree_vis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ItreeVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ItreeVisualizerUIFigure)
        end
    end
end